classdef App_s1760559_Premkumar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChordGeneratorUIFigure        matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ChorusPropertiesPanel         matlab.ui.container.Panel
        ChorusSwitch_2Label           matlab.ui.control.Label
        ChorusSwitch                  matlab.ui.control.Switch
        ChorusLamp                    matlab.ui.control.Lamp
        ChorusSinusoidsSliderLabel    matlab.ui.control.Label
        ChorusSinusoidsSlider         matlab.ui.control.Slider
        NotePropertiesPanel           matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        PluckGapsKnobLabel            matlab.ui.control.Label
        PluckGapsKnob                 matlab.ui.control.Knob
        NoteLengthsKnobLabel          matlab.ui.control.Label
        NoteLengthsKnob               matlab.ui.control.Knob
        DecayFactorKnobLabel          matlab.ui.control.Label
        DecayFactorKnob               matlab.ui.control.Knob
        PluckingToneKnobLabel         matlab.ui.control.Label
        PluckingToneKnob              matlab.ui.control.Knob
        RightPanel                    matlab.ui.container.Panel
        AMinorButton                  matlab.ui.control.Button
        CMajorqButton                 matlab.ui.control.Button
        EMinorsButton                 matlab.ui.control.Button
        GMajoraButton                 matlab.ui.control.Button
        DMinorxButton                 matlab.ui.control.Button
        FMajorzButton                 matlab.ui.control.Button
        BMinorrButton                 matlab.ui.control.Button
        DMajoreButton                 matlab.ui.control.Button
        GMinorfButton                 matlab.ui.control.Button
        BflatMajordButton             matlab.ui.control.Button
        ChordGeneratorLabel           matlab.ui.control.Label
        EchoPropertiesPanel           matlab.ui.container.Panel
        EchoSwitchLabel               matlab.ui.control.Label
        EchoSwitch                    matlab.ui.control.Switch
        EchoLamp                      matlab.ui.control.Lamp
        FeedbackCoefficientKnobLabel  matlab.ui.control.Label
        FeedbackCoefficientKnob       matlab.ui.control.Knob
        TimeDelaysKnobLabel           matlab.ui.control.Label
        TimeDelaysKnob                matlab.ui.control.Knob
        RepetitionsKnobLabel          matlab.ui.control.Label
        RepetitionsKnob               matlab.ui.control.Knob
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%     
%     RUTHU PREM KUMAR
%     FEBRUARY 2020
% 
%     GUI FOR A CHORD GENERATOR FOR COMMONLY USED CHORDS
%     THE GUI TAKES IN INPUT PARAMETERS FOR THE KARPLUS-STRONG ALGORITHM, PLUCKING GAP(s),
%     CHORUS PARAMETERS AND ECHO PARAMETERS AND PRODUCES COMMONLY USED CHORDS WITH A CLICK OF A BUTTON
%     OR PRESS OF A KEYOARD BUTTON
%     THIS GUI CAN EASILY BE EXPANDED TO INCLUDE MORE CHORDS
%     AN ALTERNATIVE GUI CAN ALSO BE MADE FROM SIMILAR PROPERTIES TO PRODUCE INDIVIDUAL NOTES(LIKE A PIANO ROLL)
%     AND MAP THEM TO KEYBOARD BUTTONS.
%     
%      DIRECTIONS
%      
%      1.TURN THE KNOBS/SLIDERS AROUND AND THEN CLICK ON A CHORD BUTTON/PRESS A CORRESPONDING KEYBOARD KEY
%      TO PRODUCE A CHORD TONE
%      2. SWITCH ON AND OFF CHORUS AND ECHO EFFECTS AS DESIRED
%      3. THE CORRESPONDING KEYBOARD KEY IS WRITTEN IN BRACKETS NEXT TO THE CHORD NAME
% 
%        NOTE : TAP SOMEWHERE ELSE AFTER ADJUSTING THE CHORUS SLIDER VALUE TO CONFIRM CHANGE TO 
%        THE VALUE. SLIDER VALUES DO NOT AUTO UPDATE LIKE KNOBS.
% 
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
    
    properties (Access = private)
        % Karplus-Strong Chord Parameters
        number;
        rho;
        R,
        dur;
        
        % Time duration between plucks (s)
        gap;
        
        % Chorus Parameters
        
        N_rand;
        
        % Echo Parameters
        
        fb;
        time;
        rep;
        
        
        % Output signal y
        y;
        
        % Sample Rate 
        Fs = 44100;
    end
                  
    methods (Access = private)
        
        function [] = exec(app,num)
            
            clear app.y;          % Clear output vector y
            
            % Assign simulation parameters based on input knob/slider
            % values
            app.number = num;
            app.gap = app.PluckGapsKnob.Value;            
            app.rho = app.DecayFactorKnob.Value;
            app.R = app.PluckingToneKnob.Value;
            app.dur = app.NoteLengthsKnob.Value;                      
            
            % Output Signal y
            app.y = chord_s1760559_Premkumar(app.number,app.gap,app.rho,app.R,app.dur);
            
            % If the Chorus switch is on
            if strcmp(app.ChorusSwitch.Value,'On') == 1
                
                app.N_rand = app.ChorusSinusoidsSlider.Value;
                app.y = chorus_s1760559_Premkumar(app.y,app.N_rand); 
                
            end
            
            % If the Echo Switch is on
            if strcmp(app.EchoSwitch.Value,'On') == 1
                
                app.fb = app.FeedbackCoefficientKnob.Value;
                app.time = app.TimeDelaysKnob.Value;
                app.rep = app.RepetitionsKnob.Value;
                echor_s1760559_Premkumar(app.y,app.fb,app.time,app.rep);
                
            else
                soundsc(app.y,app.Fs);
            end            
                                                       
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Initialise Start positions
            app.ChorusSwitch.Value = 'Off';
            app.EchoSwitch.Value = 'Off' ;
            app.ChorusLamp.Color = [0.5 0.5 0.5];
            app.EchoLamp.Color = [0.5 0.5 0.5];
          
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ChordGeneratorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {490, 490};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {546, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: AMinorButton
        function AminorButtonPushed(app, event)
            exec(app,1);                                               
        end

        % Value changed function: ChorusSwitch
        function ChorusSwitchValueChanged(app, event)
       
            % Change lamp colour when switch is turned on or off
            if strcmp(app.ChorusSwitch.Value,'On') == 1
                app.ChorusLamp.Color = [0 1 0];
                app.ChorusSwitch.FontColor = [0 1 0];
            else
                app.ChorusLamp.Color = [0.5 0.5 0.5];
                app.ChorusSwitch.FontColor = [0 0 0];
            end
        
        end

        % Callback function
        function PluckGapKnobValueChanged(app, event)
        
        end

        % Button pushed function: CMajorqButton
        function CMajorqButtonPushed(app, event)
            exec(app,2);
        end

        % Button pushed function: GMajoraButton
        function GMajoraButtonPushed(app, event)
            exec(app,3);
        end

        % Button pushed function: EMinorsButton
        function EMinorsButtonPushed(app, event)
            exec(app,4);
        end

        % Button pushed function: DMinorxButton
        function DMinorxButtonPushed(app, event)
            exec(app,5);
        end

        % Button pushed function: FMajorzButton
        function FMajorzButtonPushed(app, event)
            exec(app,6);
        end

        % Button pushed function: BMinorrButton
        function BMinorrButtonPushed(app, event)
            exec(app,7);
        end

        % Button pushed function: DMajoreButton
        function DMajoreButtonPushed(app, event)
            exec(app,8);
        end

        % Button pushed function: GMinorfButton
        function GMinorfButtonPushed(app, event)
            exec(app,9);
        end

        % Button pushed function: BflatMajordButton
        function BflatMajordButtonPushed(app, event)
            exec(app,10);
        end

        % Value changed function: EchoSwitch
        function EchoSwitchValueChanged(app, event)
            
            % Change lamp colour when switch is turned on or off
            if strcmp(app.EchoSwitch.Value,'On') == 1
                app.EchoLamp.Color = [0 1 0];
                app.EchoSwitch.FontColor = [0 1 0];
                
            else
                app.EchoLamp.Color = [0.5 0.5 0.5];
                app.EchoSwitch.FontColor = [0 0 0];
            end
        
            
        end

        % Key press function: ChordGeneratorUIFigure
        function ChordGeneratorUIFigureKeyPress(app, event)
            
            % Function to map key presses to chord buttons
            
            key = event.Key;
            if strcmp(key,'q') == 1 
                CMajorqButtonPushed(app);
            end
            if strcmp(key,'w') == 1 
                AminorButtonPushed(app);
            end
            if strcmp(key,'a') == 1 
                GMajoraButtonPushed(app);
            end
            if strcmp(key,'s') == 1 
                EMinorsButtonPushed(app);
            end
            if strcmp(key,'z') == 1 
                FMajorzButtonPushed(app);
            end
            if strcmp(key,'x') == 1 
                DMinorxButtonPushed(app);
            end
            if strcmp(key,'e') == 1 
                DMajoreButtonPushed(app);
            end
            if strcmp(key,'r') == 1 
                BMinorrButtonPushed(app);
            end
            if strcmp(key,'d') == 1 
                BflatMajordButtonPushed(app);
            end
            if strcmp(key,'f') == 1 
                GMinorfButtonPushed(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChordGeneratorUIFigure = uifigure('Visible', 'off');
            app.ChordGeneratorUIFigure.Name = 'Pulse Generator';
            app.ChordGeneratorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ChordGeneratorUIFigure.KeyPressFcn = createCallbackFcn(app, @ChordGeneratorUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.ChordGeneratorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ChorusPropertiesPanel = uipanel(app.LeftPanel);
            app.ChorusPropertiesPanel.Title = 'Chorus Properties';
            app.ChorusSwitch_2Label = uilabel(app.ChorusPropertiesPanel);
            app.ChorusSwitch_2Label.Text = 'Chorus';
            app.ChorusSwitch = uiswitch(app.ChorusPropertiesPanel, 'slider');
            app.ChorusSwitch.ValueChangedFcn = createCallbackFcn(app, @ChorusSwitchValueChanged, true);
            app.ChorusLamp = uilamp(app.ChorusPropertiesPanel);
            app.ChorusSinusoidsSliderLabel = uilabel(app.ChorusPropertiesPanel);
            app.ChorusSinusoidsSliderLabel.Text = {'Chorus Sinusoids'; ''};
            app.ChorusSinusoidsSlider = uislider(app.ChorusPropertiesPanel);
            app.ChorusSinusoidsSlider.Limits = [1 50];
            app.ChorusSinusoidsSlider.Value = 20;
            app.NotePropertiesPanel = uipanel(app.LeftPanel);
            app.NotePropertiesPanel.Title = 'Note Properties';
            app.GridLayout2 = uigridlayout(app.NotePropertiesPanel);
            app.PluckGapsKnobLabel = uilabel(app.GridLayout2);
            app.PluckGapsKnobLabel.Text = 'Pluck Gap (s)';
            app.PluckGapsKnob = uiknob(app.GridLayout2, 'continuous');
            app.PluckGapsKnob.Limits = [0.01 1];
            app.PluckGapsKnob.Value = 0.05;
            app.NoteLengthsKnobLabel = uilabel(app.GridLayout2);
            app.NoteLengthsKnobLabel.Text = 'Note Length (s)';
            app.NoteLengthsKnob = uiknob(app.GridLayout2, 'continuous');
            app.NoteLengthsKnob.Limits = [0.25 4];
            app.NoteLengthsKnob.MajorTicks = [0.25 1 2 3 4];
            app.NoteLengthsKnob.MajorTickLabels = {'0.25', '1', '2', '3', '4'};
            app.NoteLengthsKnob.MinorTicks = [];
            app.NoteLengthsKnob.Value = 1;
            app.DecayFactorKnobLabel = uilabel(app.GridLayout2);
            app.DecayFactorKnobLabel.Text = {'Decay Factor'; ''; ''};
            app.DecayFactorKnob = uiknob(app.GridLayout2, 'continuous');
            app.DecayFactorKnob.Limits = [0.9 0.999];
            app.DecayFactorKnob.Value = 0.985;
            app.PluckingToneKnobLabel = uilabel(app.GridLayout2);
            app.PluckingToneKnobLabel.Text = {'Plucking Tone'; ''};
            app.PluckingToneKnob = uiknob(app.GridLayout2, 'continuous');
            app.PluckingToneKnob.Limits = [0.1 0.99];
            app.PluckingToneKnob.Value = 0.9;
            app.RightPanel = uipanel(app.GridLayout);
            app.AMinorButton = uibutton(app.RightPanel, 'push');
            app.AMinorButton.ButtonPushedFcn = createCallbackFcn(app, @AminorButtonPushed, true);
            app.AMinorButton.Text = 'A Minor (w)';
            app.CMajorqButton = uibutton(app.RightPanel, 'push');
            app.CMajorqButton.ButtonPushedFcn = createCallbackFcn(app, @CMajorqButtonPushed, true);
            app.CMajorqButton.Text = 'C Major (q)';
            app.EMinorsButton = uibutton(app.RightPanel, 'push');
            app.EMinorsButton.ButtonPushedFcn = createCallbackFcn(app, @EMinorsButtonPushed, true);
            app.EMinorsButton.Text = 'E Minor (s)';
            app.GMajoraButton = uibutton(app.RightPanel, 'push');
            app.GMajoraButton.ButtonPushedFcn = createCallbackFcn(app, @GMajoraButtonPushed, true);
            app.GMajoraButton.Text = 'G Major (a)';
            app.DMinorxButton = uibutton(app.RightPanel, 'push');
            app.DMinorxButton.ButtonPushedFcn = createCallbackFcn(app, @DMinorxButtonPushed, true);
            app.DMinorxButton.Text = 'D Minor (x)';
            app.FMajorzButton = uibutton(app.RightPanel, 'push');
            app.FMajorzButton.ButtonPushedFcn = createCallbackFcn(app, @FMajorzButtonPushed, true);
            app.FMajorzButton.Text = 'F Major (z)';
            app.BMinorrButton = uibutton(app.RightPanel, 'push');
            app.BMinorrButton.ButtonPushedFcn = createCallbackFcn(app, @BMinorrButtonPushed, true);
            app.BMinorrButton.Text = 'B Minor (r)';
            app.DMajoreButton = uibutton(app.RightPanel, 'push');
            app.DMajoreButton.ButtonPushedFcn = createCallbackFcn(app, @DMajoreButtonPushed, true);
            app.DMajoreButton.Text = 'D Major (e)';
            app.GMinorfButton = uibutton(app.RightPanel, 'push');
            app.GMinorfButton.ButtonPushedFcn = createCallbackFcn(app, @GMinorfButtonPushed, true);
            app.GMinorfButton.Text = 'G Minor (f)';
            app.BflatMajordButton = uibutton(app.RightPanel, 'push');
            app.BflatMajordButton.ButtonPushedFcn = createCallbackFcn(app, @BflatMajordButtonPushed, true);
            app.BflatMajordButton.Text = 'B flat Major (d)';
            app.ChordGeneratorLabel = uilabel(app.RightPanel);
            app.ChordGeneratorLabel.Text = 'Chord Generator';
            app.EchoPropertiesPanel = uipanel(app.RightPanel);
            app.EchoPropertiesPanel.Title = 'Echo Properties';
            app.EchoSwitchLabel = uilabel(app.EchoPropertiesPanel);
            app.EchoSwitchLabel.Text = 'Echo';
            app.EchoSwitch = uiswitch(app.EchoPropertiesPanel, 'slider');
            app.EchoSwitch.ValueChangedFcn = createCallbackFcn(app, @EchoSwitchValueChanged, true);
            app.EchoLamp = uilamp(app.EchoPropertiesPanel);
            app.FeedbackCoefficientKnobLabel = uilabel(app.EchoPropertiesPanel);
            app.FeedbackCoefficientKnobLabel.Text = 'Feedback Coefficient';
            app.FeedbackCoefficientKnob = uiknob(app.EchoPropertiesPanel, 'continuous');
            app.FeedbackCoefficientKnob.Limits = [0.05 0.9];
            app.FeedbackCoefficientKnob.Value = 0.5;
            app.TimeDelaysKnobLabel = uilabel(app.EchoPropertiesPanel);
            app.TimeDelaysKnobLabel.Text = 'Time Delay (s)';
            app.TimeDelaysKnob = uiknob(app.EchoPropertiesPanel, 'continuous');
            app.TimeDelaysKnob.Limits = [0.1 2];
            app.TimeDelaysKnob.Value = 0.5;
            app.RepetitionsKnobLabel = uilabel(app.EchoPropertiesPanel);
            app.RepetitionsKnobLabel.Text = 'Repetitions';
            app.RepetitionsKnob = uiknob(app.EchoPropertiesPanel, 'continuous');
            app.RepetitionsKnob.Limits = [1 10];
            app.RepetitionsKnob.Value = 5;
            % Show the figure after all components are created
            app.ChordGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_s1760559_Premkumar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChordGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChordGeneratorUIFigure)
        end
    end
end